classdef mainfrm < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        AddNoiseButton          matlab.ui.control.Button
        ClearSignalsButton      matlab.ui.control.Button
        NoiseReductionButton    matlab.ui.control.Button
        ButtonGroup             matlab.ui.container.ButtonGroup
        PulseSignalButton       matlab.ui.control.RadioButton
        SquareWaveSignalButton  matlab.ui.control.RadioButton
        SineWaveSignalButton    matlab.ui.control.RadioButton
        EnableNoiseCheckBox     matlab.ui.control.CheckBox
        GenerateSignalsButton   matlab.ui.control.Button
        TabGroup                matlab.ui.container.TabGroup
        Tab                     matlab.ui.container.Tab
        AmplitudeEdit           matlab.ui.control.EditField
        Label_4                 matlab.ui.control.Label
        SignalFrequencyEdit     matlab.ui.control.EditField
        Label_3                 matlab.ui.control.Label
        SamplingFrequencyEdit   matlab.ui.control.EditField
        Label_2                 matlab.ui.control.Label
        Label                   matlab.ui.control.Label
        Tab_3                   matlab.ui.container.Tab
        Tab_2                   matlab.ui.container.Tab
        UIAxes                  matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GenerateSignalsButton
        function GenerateSignalsButtonPushed(app, event)
            % 生成正弦信号
            % 从文本框中获取采样频率
            fs = str2double(app.SamplingFrequencyEdit.Value);
            t = 0:1/fs:1-1/fs; % 时间序列
            f = str2double(app.SignalFrequencyEdit.Value); % 正弦信号频率
            A = str2double(app.AmplitudeEdit.Value); % 正弦信号幅度
            x = A*sin(2*pi*f*t); % 生成正弦信号
            
            % 可视化展示
            % figure;
            subplot(2,1,1);
            plot(app.UIAxes,t,x);
            xlabel(app.UIAxes,'Time (s)');
            ylabel(app.UIAxes,'Amplitude');
            title(app.UIAxes,'Original Signal');
            
            % 添加噪声
            noise = 0.1*randn(size(x)); % 生成高斯白噪声
            y = x + noise; % 添加噪声
            
            % 可视化展示
            subplot(2,1,2);
            plot(app.UIAxes,t,y);
            xlabel(app.UIAxes,'Time (s)');
            ylabel(app.UIAxes,'Amplitude');
            title(app.UIAxes,'Purre Signal');
        end

        % Button pushed function: ClearSignalsButton
        function ClearSignalsButtonPushed(app, event)
            cla(app.UIAxes);
            title(app.UIAxes,'No Signal');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = '信号';
            app.Label = uilabel(app.Tab);
            app.Label.Text = '';
            app.Label_2 = uilabel(app.Tab);
            app.Label_2.Text = '采样频率：';
            app.SamplingFrequencyEdit = uieditfield(app.Tab, 'text');
            app.SamplingFrequencyEdit.Value = '1000';
            app.Label_3 = uilabel(app.Tab);
            app.Label_3.Text = '信号频率：';
            app.SignalFrequencyEdit = uieditfield(app.Tab, 'text');
            app.SignalFrequencyEdit.Value = '10';
            app.Label_4 = uilabel(app.Tab);
            app.Label_4.Text = '正弦信号幅度(幅值)：';
            app.AmplitudeEdit = uieditfield(app.Tab, 'text');
            app.AmplitudeEdit.Value = '1';
            app.Tab_3 = uitab(app.TabGroup);
            app.Tab_3.Title = '噪声';
            app.Tab_2 = uitab(app.TabGroup);
            app.Tab_2.Title = '滤波';
            app.GenerateSignalsButton = uibutton(app.UIFigure, 'push');
            app.GenerateSignalsButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateSignalsButtonPushed, true);
            app.GenerateSignalsButton.Text = 'GenerateSignals';
            app.EnableNoiseCheckBox = uicheckbox(app.UIFigure);
            app.EnableNoiseCheckBox.Text = 'EnableNoise';
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.Title = '信号类型';
            app.SineWaveSignalButton = uiradiobutton(app.ButtonGroup);
            app.SineWaveSignalButton.Text = 'SineWaveSignal';
            app.SineWaveSignalButton.Value = true;
            app.SquareWaveSignalButton = uiradiobutton(app.ButtonGroup);
            app.SquareWaveSignalButton.Text = 'SquareWaveSignal';
            app.PulseSignalButton = uiradiobutton(app.ButtonGroup);
            app.PulseSignalButton.Text = 'PulseSignal';
            app.NoiseReductionButton = uibutton(app.UIFigure, 'push');
            app.NoiseReductionButton.Text = 'NoiseReduction';
            app.ClearSignalsButton = uibutton(app.UIFigure, 'push');
            app.ClearSignalsButton.ButtonPushedFcn = createCallbackFcn(app, @ClearSignalsButtonPushed, true);
            app.ClearSignalsButton.Text = 'ClearSignals';
            app.AddNoiseButton = uibutton(app.UIFigure, 'push');
            app.AddNoiseButton.Text = 'AddNoise';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mainfrm

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end